classdef linear < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ModelSaveButton               matlab.ui.control.Button
        SelecttoTestButton            matlab.ui.control.Button
        TestButton                    matlab.ui.control.Button
        TrainButton                   matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        DataTab                       matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        DataTable                     matlab.ui.control.Table
        TrainTab                      matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        PvalueModel                   matlab.ui.control.NumericEditField
        pvalueEditFieldLabel          matlab.ui.control.Label
        FstatisticModel               matlab.ui.control.NumericEditField
        FstatisticEditFieldLabel      matlab.ui.control.Label
        AdjustedR2Model               matlab.ui.control.NumericEditField
        AdjustedR2EditFieldLabel      matlab.ui.control.Label
        RsquaredR2Model               matlab.ui.control.NumericEditField
        R2Label                       matlab.ui.control.Label
        RMSEModel                     matlab.ui.control.NumericEditField
        RMSEEditFieldLabel            matlab.ui.control.Label
        SigTable                      matlab.ui.control.Table
        ModelTable                    matlab.ui.control.Table
        RandomSeedSlider              matlab.ui.control.Slider
        RandomSeedSliderLabel         matlab.ui.control.Label
        TrainSizeDropDown             matlab.ui.control.DropDown
        TrainSizeDropDownLabel        matlab.ui.control.Label
        TestTab                       matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        TrainRMSEEditField            matlab.ui.control.NumericEditField
        TrainRMSEEditFieldLabel       matlab.ui.control.Label
        TrainMAEEditField             matlab.ui.control.NumericEditField
        TrainMAEEditFieldLabel        matlab.ui.control.Label
        TrainMSEEditField             matlab.ui.control.NumericEditField
        TrainMSEEditFieldLabel        matlab.ui.control.Label
        TrainREditField               matlab.ui.control.NumericEditField
        TrainREditFieldLabel          matlab.ui.control.Label
        TestREditField                matlab.ui.control.NumericEditField
        TestRLabel                    matlab.ui.control.Label
        TestRMSEEditField             matlab.ui.control.NumericEditField
        TestRMSELabel                 matlab.ui.control.Label
        TestMSEEditField              matlab.ui.control.NumericEditField
        TestMSELabel                  matlab.ui.control.Label
        TestMAEEditField              matlab.ui.control.NumericEditField
        TestMAELabel                  matlab.ui.control.Label
        TestTrueVSPred                matlab.ui.control.UIAxes
        TrainTrueVSPred               matlab.ui.control.UIAxes
        TestResiduals                 matlab.ui.control.UIAxes
        TrainResiduals                matlab.ui.control.UIAxes
        SelectasampletotestTab        matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        SampleIndexSlider             matlab.ui.control.Slider
        SampleIndexSliderLabel        matlab.ui.control.Label
        MPGSamplePredictionEditField  matlab.ui.control.NumericEditField
        MPGSamplePredictionEditFieldLabel  matlab.ui.control.Label
        MPGSampleTrueEditField        matlab.ui.control.NumericEditField
        MPGSampleTrueEditFieldLabel   matlab.ui.control.Label
        SelectXUITable                matlab.ui.control.Table
        SelectModelUITable            matlab.ui.control.Table
        QuitButton                    matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data 

        Yes string = string(helper.YesNo.Yes);
        No string = string(helper.YesNo.No);

        Off string = string(helper.OffOn.Off);
        On string = string(helper.OffOn.On);

        XTrain
        YTrain
        XTest
        YTest

        Model
        ModelName string = "linearModel.mat";
        ChangedValue
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            choice = questdlg("Do you want to QUIT?", "WARNING", app.Yes, app.No, app.No);
            switch choice
                case app.Yes
                    delete(app)
                case app.No
                    return
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [X, Y] = helper.dataLoader();
            app.Data = [X, Y];

            app.DataTable.Data = app.Data;

            app.LoadDataButton.Enable = app.Off;
            app.ResetButton.Visible = app.On;
            app.TrainButton.Visible = app.On;
            msgbox("The data has been cleared and loaded successfully!", "INFORMATION", "help");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            choice = questdlg("Do you want to clear the data?", "WARNING", app.Yes, app.No, app.No);
            switch choice
                case app.Yes
                    app.Data = [];
                    app.DataTable.Data = [];

                    app.TrainSizeDropDown.Value = "80%";
                    app.RandomSeedSlider.Value = 27;
                    app.RMSEModel.Value = 0;
                    app.RsquaredR2Model.Value = 0;
                    app.AdjustedR2Model.Value = 0;
                    app.FstatisticModel.Value = 0;
                    app.PvalueModel.Value = 0;
                    app.SigTable.Data = [];
                    app.ModelTable.Data = [];

                    app.TestMAEEditField.Value = 0;
                    app.TestMSEEditField.Value = 0;
                    app.TestRMSEEditField.Value = 0;
                    app.TestREditField.Value = 0;

                    app.TrainMAEEditField.Value = 0;
                    app.TrainMSEEditField.Value = 0; 
                    app.TrainRMSEEditField.Value = 0;
                    app.TrainREditField.Value = 0;

                    cla(app.TestTrueVSPred);
                    cla(app.TestResiduals);
                    cla(app.TrainTrueVSPred);
                    cla(app.TrainResiduals);

                    app.SampleIndexSlider.Value = 27;
                    app.MPGSampleTrueEditField.Value = 0;
                    app.MPGSamplePredictionEditField.Value = 0;
                    app.SampleIndexSliderLabel.Text = "Sample Index";
                    app.SelectModelUITable.Data = [];
                    app.SelectXUITable.Data = [];

                    app.LoadDataButton.Enable = app.On;
                    app.TrainButton.Enable = app.On;
                    app.TestButton.Enable = app.On;
                    app.ModelSaveButton.Enable = app.On;
                    app.ResetButton.Visible = app.Off;
                    app.TrainButton.Visible = app.Off;
                    app.TestButton.Visible = app.Off;
                    app.ModelSaveButton.Visible = app.Off;
                    app.SelecttoTestButton.Visible = app.Off;
                    app.SelecttoTestButton.Visible = app.Off;

                    delete(app.ModelName);

                    msgbox("All data has been cleared!", "INFORMATION", "help");
                case app.No
                    return
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            value = strrep(app.TrainSizeDropDown.Value, "%", "");
            trainSize = str2double(value) / 100;
            seed = app.RandomSeedSlider.Value;
            disp(repmat('*', 1, 50));
            disp("Train Parameters")
            disp(repmat('-', 1, 50));
            fprintf("Train size is %.2f\n", trainSize);
            fprintf("The random seed is %d\n", seed);
            disp(repmat('*', 1, 50));

            [app.XTrain, app.XTest, app.YTrain, app.YTest] = helper.trainTestSplitter(app.Data, seed, trainSize);
            app.Model = fitlm(app.XTrain, app.YTrain);
            disp(repmat('*', 1, 50));
            disp("Linear Model Class")
            disp(repmat('-', 1, 50));
            disp("The model class is " + class(app.Model));
            disp(repmat('*', 1, 50));

            % Set the range of 'select to test'
            app.SampleIndexSlider.Limits = [1, length(app.XTest)];
            app.ChangedValue = round(app.SampleIndexSlider.Value);
            
            modelTable = app.Model.Coefficients;
            % Display all cols
            app.ModelTable.RowName = modelTable.Properties.RowNames; 
            app.ModelTable.Data = modelTable(:, :);

            % Highlight the significant col and display them
            significantRows = modelTable.pValue <= 0.05;
            app.SigTable.Data = modelTable(significantRows, :);
            app.SigTable.RowName = modelTable.Properties.RowNames(significantRows);

            disp(repmat('*', 1, 50));
            disp(app.Model)
            disp(repmat('*', 1, 50));
            % 参数个数，包括截距
            k = app.Model.NumCoefficients;
            disp("Number of coefficients (k) is " + k);
            % 样本数
            nObs = app.Model.NumObservations;
            disp("Number of observations is " + nObs);
            % 自由度
            dof = app.Model.DFE;
            disp("Error degrees of freedom is " + dof);
            % RMSE
            rmse = app.Model.RMSE;
            app.RMSEModel.Value = rmse;
            % R² 和 Adjusted R²
            R2 = app.Model.Rsquared.Ordinary;
            adjR2 = app.Model.Rsquared.Adjusted;
            app.RsquaredR2Model.Value = R2;
            app.AdjustedR2Model.Value = adjR2;
            % F-statistic 和 p-value
            Fstat = (R2 / (k - 1)) / ((1 - R2) / (nObs - k));
            FpVal = 1 - fcdf(Fstat, k - 1, nObs - k);
            app.FstatisticModel.Value = Fstat;
            app.PvalueModel.Value = FpVal;

            app.TrainButton.Enable = app.Off;
            app.TestButton.Visible = app.On;
            msgbox("Congratulation! Linear Model has been trained successfully!", "INFORMATION", "help");
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            YPredTest = predict(app.Model, app.XTest);
            YPredTrain = predict(app.Model, app.XTrain);

            % Test pred
            % 平均绝对误差 MAE
            MAETest = mean(abs(app.YTest - YPredTest));
            app.TestMAEEditField.Value = MAETest;
            % 均方误差 MSE
            MSETest = mean((app.YTest - YPredTest).^2);
            app.TestMSEEditField.Value = MSETest;
            % 均方根误差 RMSE
            RMSETest = sqrt(MSETest); 
            app.TestRMSEEditField.Value = RMSETest;
            % R² (测试集)
            SSResTest = sum((app.YTest - YPredTest).^2);
            SSTotTest = sum((app.YTest - mean(app.YTest)).^2);
            R2Test = 1 - SSResTest/SSTotTest;
            app.TestREditField.Value = R2Test;

            % Train pred
            % 平均绝对误差 MAE
            MAETrain = mean(abs(app.YTrain - YPredTrain));
            app.TrainMAEEditField.Value = MAETrain;
            % 均方误差 MSE
            MSETrain = mean((app.YTrain - YPredTrain).^2);
            app.TrainMSEEditField.Value = MSETrain;
            % 均方根误差 RMSE
            RMSETrain = sqrt(MSETrain); 
            app.TrainRMSEEditField.Value = RMSETrain;
            % R² (测试集)
            SSResTrain = sum((app.YTrain - YPredTrain).^2);
            SSTotTrain = sum((app.YTrain - mean(app.YTrain)).^2);
            R2Train = 1 - SSResTrain/SSTotTrain;
            app.TrainREditField.Value = R2Train;

            % Test Chart
            scatter(app.TestTrueVSPred, app.YTest, YPredTest, "filled");
            xlabel(app.TestTrueVSPred, "True");
            ylabel(app.TestTrueVSPred, "Pred");
            grid(app.TestTrueVSPred, app.On);
            refline(app.TestTrueVSPred, 1, 0); 
            % The Test Chart of of Residuals
            residuals = app.YTest - YPredTest;
            scatter(app.TestResiduals, app.YTest, residuals, "filled");
            yline(app.TestResiduals, 0, "-r"); % The Baseline of Residuals
            xlabel(app.TestResiduals, "True");
            ylabel(app.TestResiduals, "Residual");
            grid(app.TestResiduals, app.On);

            % Train Chart
            scatter(app.TrainTrueVSPred, app.YTrain, YPredTrain, "filled");
            xlabel(app.TrainTrueVSPred, "True");
            ylabel(app.TrainTrueVSPred, "Pred");
            grid(app.TrainTrueVSPred, app.On);
            refline(app.TrainTrueVSPred, 1, 0); 
            % The Train Chart of of Residuals
            residuals = app.YTrain - YPredTrain;
            scatter(app.TrainResiduals, app.YTrain, residuals, "filled");
            yline(app.TrainResiduals, 0, "-r"); % The Baseline of Residuals
            xlabel(app.TrainResiduals, "True");
            ylabel(app.TrainResiduals, "Residual");
            grid(app.TrainResiduals, app.On);

            app.TestButton.Enable = app.Off;
            app.ModelSaveButton.Visible = app.On;
            msgbox("Congratulation! The test data has been tested successfully!", "INFORMATION", "help");
        end

        % Button pushed function: ModelSaveButton
        function ModelSaveButtonPushed(app, event)
            tempModel = app.Model;
            save(app.ModelName, 'tempModel');

            app.ModelSaveButton.Enable = app.Off;
            app.SelecttoTestButton.Visible = app.On;
            msgbox("Congratulation! The Linear Model has been saved successfully!", "INFORMATION", "help");
        end

        % Button pushed function: SelecttoTestButton
        function SelecttoTestButtonPushed(app, event)
            load(app.ModelName, 'tempModel')

            modelInfo = tempModel.Coefficients;
            % Display all cols
            app.SelectModelUITable.RowName = modelInfo.Properties.RowNames; 
            app.SelectModelUITable.Data = modelInfo(:, :);
            
            fprintf("The changed index is %d", app.ChangedValue);
            x = app.XTest(app.ChangedValue, :);
            app.SelectXUITable.Data = x;

            yTrue = app.YTest(app.ChangedValue);
            yPred = predict(tempModel, x);
            fprintf("The value of yTrue is %d", yTrue);
            fprintf("The value of yPred is %d", yPred);
            app.MPGSampleTrueEditField.Value = yTrue;
            app.MPGSamplePredictionEditField.Value = yPred;

            info = sprintf("The %d sample from the test data has been predicted successfully!", app.ChangedValue);
            msgbox(info, "INFORMATION", "help");
        end

        % Value changed function: SampleIndexSlider
        function SampleIndexSliderValueChanged(app, event)
            value = app.SampleIndexSlider.Value;
            app.ChangedValue = round(value);
            app.SampleIndexSliderLabel.Text = sprintf("Select %d", app.ChangedValue);
            fprintf("The changed index is %d", app.ChangedValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LoadDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.QuitButton = uibutton(app.GridLayout, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GridLayout3 = uigridlayout(app.DataTab);
            app.DataTable = uitable(app.GridLayout3);
            app.DataTable.ColumnName = {'Cylinders'; 'Displacement'; 'Horsepower'; 'Weight'; 'Acceleration'; 'Model_Year'; 'MPG'};
            app.DataTable.RowName = {};
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.GridLayout2 = uigridlayout(app.TrainTab);
            app.TrainSizeDropDownLabel = uilabel(app.GridLayout2);
            app.TrainSizeDropDownLabel.Text = 'Train Size';
            app.TrainSizeDropDown = uidropdown(app.GridLayout2);
            app.TrainSizeDropDown.Items = {'60%', '70%', '80%', '90%'};
            app.TrainSizeDropDown.Value = '80%';
            app.RandomSeedSliderLabel = uilabel(app.GridLayout2);
            app.RandomSeedSliderLabel.Text = 'Random Seed';
            app.RandomSeedSlider = uislider(app.GridLayout2);
            app.RandomSeedSlider.Value = 27;
            app.ModelTable = uitable(app.GridLayout2);
            app.ModelTable.ColumnName = {'Estimate'; 'SE'; 'tStat'; 'pValue'};
            app.ModelTable.RowName = {};
            app.SigTable = uitable(app.GridLayout2);
            app.SigTable.ColumnName = {'Estimate'; 'SE'; 'tStat'; 'pValue'};
            app.SigTable.RowName = {};
            app.RMSEEditFieldLabel = uilabel(app.GridLayout2);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEModel = uieditfield(app.GridLayout2, 'numeric');
            app.RMSEModel.Editable = 'off';
            app.R2Label = uilabel(app.GridLayout2);
            app.R2Label.Text = 'R2';
            app.RsquaredR2Model = uieditfield(app.GridLayout2, 'numeric');
            app.RsquaredR2Model.Editable = 'off';
            app.AdjustedR2EditFieldLabel = uilabel(app.GridLayout2);
            app.AdjustedR2EditFieldLabel.Text = 'Adjusted R2';
            app.AdjustedR2Model = uieditfield(app.GridLayout2, 'numeric');
            app.AdjustedR2Model.Editable = 'off';
            app.FstatisticEditFieldLabel = uilabel(app.GridLayout2);
            app.FstatisticEditFieldLabel.Text = 'F-statistic';
            app.FstatisticModel = uieditfield(app.GridLayout2, 'numeric');
            app.FstatisticModel.Editable = 'off';
            app.pvalueEditFieldLabel = uilabel(app.GridLayout2);
            app.pvalueEditFieldLabel.Text = 'p-value';
            app.PvalueModel = uieditfield(app.GridLayout2, 'numeric');
            app.PvalueModel.Editable = 'off';
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.GridLayout4 = uigridlayout(app.TestTab);
            app.TrainResiduals = uiaxes(app.GridLayout4);
            title(app.TrainResiduals, 'Train: Residual Plot')
            xlabel(app.TrainResiduals, 'X')
            ylabel(app.TrainResiduals, 'Y')
            zlabel(app.TrainResiduals, 'Z')
            app.TestResiduals = uiaxes(app.GridLayout4);
            title(app.TestResiduals, 'Test: Residual Plot')
            xlabel(app.TestResiduals, 'X')
            ylabel(app.TestResiduals, 'Y')
            zlabel(app.TestResiduals, 'Z')
            app.TrainTrueVSPred = uiaxes(app.GridLayout4);
            title(app.TrainTrueVSPred, 'Test Scatter: True VS Pred')
            xlabel(app.TrainTrueVSPred, 'X')
            ylabel(app.TrainTrueVSPred, 'Y')
            zlabel(app.TrainTrueVSPred, 'Z')
            app.TestTrueVSPred = uiaxes(app.GridLayout4);
            title(app.TestTrueVSPred, 'Test Scatter: True VS Pred')
            xlabel(app.TestTrueVSPred, 'X')
            ylabel(app.TestTrueVSPred, 'Y')
            zlabel(app.TestTrueVSPred, 'Z')
            app.TestMAELabel = uilabel(app.GridLayout4);
            app.TestMAELabel.Text = 'Test MAE';
            app.TestMAEEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TestMAEEditField.Editable = 'off';
            app.TestMSELabel = uilabel(app.GridLayout4);
            app.TestMSELabel.Text = 'Test MSE';
            app.TestMSEEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TestMSEEditField.Editable = 'off';
            app.TestRMSELabel = uilabel(app.GridLayout4);
            app.TestRMSELabel.Text = 'Test RMSE';
            app.TestRMSEEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TestRMSEEditField.Editable = 'off';
            app.TestRLabel = uilabel(app.GridLayout4);
            app.TestRLabel.Text = 'Test R²';
            app.TestREditField = uieditfield(app.GridLayout4, 'numeric');
            app.TestREditField.Editable = 'off';
            app.TrainREditFieldLabel = uilabel(app.GridLayout4);
            app.TrainREditFieldLabel.Text = 'Train R²';
            app.TrainREditField = uieditfield(app.GridLayout4, 'numeric');
            app.TrainREditField.Editable = 'off';
            app.TrainMSEEditFieldLabel = uilabel(app.GridLayout4);
            app.TrainMSEEditFieldLabel.Text = 'Train MSE';
            app.TrainMSEEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TrainMSEEditField.Editable = 'off';
            app.TrainMAEEditFieldLabel = uilabel(app.GridLayout4);
            app.TrainMAEEditFieldLabel.Text = 'Train MAE';
            app.TrainMAEEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TrainMAEEditField.Editable = 'off';
            app.TrainRMSEEditFieldLabel = uilabel(app.GridLayout4);
            app.TrainRMSEEditFieldLabel.Text = 'Train RMSE';
            app.TrainRMSEEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TrainRMSEEditField.Editable = 'off';
            app.SelectasampletotestTab = uitab(app.TabGroup);
            app.SelectasampletotestTab.Title = 'Select a sample to test';
            app.GridLayout5 = uigridlayout(app.SelectasampletotestTab);
            app.SelectModelUITable = uitable(app.GridLayout5);
            app.SelectModelUITable.ColumnName = {'Estimate'; 'SE'; 'tStat'; 'pValue'};
            app.SelectModelUITable.RowName = {};
            app.SelectXUITable = uitable(app.GridLayout5);
            app.SelectXUITable.ColumnName = {'Cylinders'; 'Displacement'; 'Horsepower'; 'Weight'; 'Acceleration'; 'Model_Year'};
            app.SelectXUITable.RowName = {};
            app.MPGSampleTrueEditFieldLabel = uilabel(app.GridLayout5);
            app.MPGSampleTrueEditFieldLabel.Text = 'MPG Sample True';
            app.MPGSampleTrueEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MPGSamplePredictionEditFieldLabel = uilabel(app.GridLayout5);
            app.MPGSamplePredictionEditFieldLabel.Text = 'MPG Sample Prediction';
            app.MPGSamplePredictionEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SampleIndexSliderLabel = uilabel(app.GridLayout5);
            app.SampleIndexSliderLabel.Text = 'Sample Index';
            app.SampleIndexSlider = uislider(app.GridLayout5);
            app.SampleIndexSlider.Limits = [1 100];
            app.SampleIndexSlider.ValueChangedFcn = createCallbackFcn(app, @SampleIndexSliderValueChanged, true);
            app.SampleIndexSlider.Value = 27;
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.TrainButton = uibutton(app.GridLayout, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Visible = 'off';
            app.TrainButton.Text = 'Train';
            app.TestButton = uibutton(app.GridLayout, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Visible = 'off';
            app.TestButton.Text = 'Test';
            app.SelecttoTestButton = uibutton(app.GridLayout, 'push');
            app.SelecttoTestButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttoTestButtonPushed, true);
            app.SelecttoTestButton.Visible = 'off';
            app.SelecttoTestButton.Text = 'Select to Test';
            app.ModelSaveButton = uibutton(app.GridLayout, 'push');
            app.ModelSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ModelSaveButtonPushed, true);
            app.ModelSaveButton.Visible = 'off';
            app.ModelSaveButton.Text = 'Model Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linear

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end